classdef LAI_calc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LAIDetectionUIFigure            matlab.ui.Figure
        GridLayout4                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        ImportTab                       matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ImportfilesPanel                matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        SearchFileButton_3              matlab.ui.control.Button
        paramFile                       matlab.ui.control.EditField
        ParametersLabel                 matlab.ui.control.Label
        ErrorLabel                      matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        AreaofimagemEditField           matlab.ui.control.NumericEditField
        AreaofimagemEditFieldLabel      matlab.ui.control.Label
        SearchFileButton_2              matlab.ui.control.Button
        SearchFileButton                matlab.ui.control.Button
        plyFile                         matlab.ui.control.EditField
        content2_2                      matlab.ui.control.Label
        bagFile                         matlab.ui.control.EditField
        content2                        matlab.ui.control.Label
        RGBImg                          matlab.ui.control.UIAxes
        DepthImg                        matlab.ui.control.UIAxes
        ExamineTab                      matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        DepthTab                        matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        DepthInfo                       matlab.ui.control.TextArea
        LayerdefinitionPanel            matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        ApplyLayerDefButton             matlab.ui.control.Button
        TotalHeightSpinner              matlab.ui.control.Spinner
        TotalHeightmSpinnerLabel        matlab.ui.control.Label
        LayerThicknessSpinner           matlab.ui.control.Spinner
        LayerThicknessmSpinnerLabel     matlab.ui.control.Label
        ResetLayerHeightButton          matlab.ui.control.Button
        ShowLayerSpinner                matlab.ui.control.Spinner
        ShowLayerSpinnerLabel           matlab.ui.control.Label
        AutoplanerotationPanel          matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        ResetAutoRotationButton         matlab.ui.control.Button
        ZNormSwitch                     matlab.ui.control.Switch
        ZSwitchLabel                    matlab.ui.control.Label
        YNormSwitch                     matlab.ui.control.Switch
        YSwitchLabel                    matlab.ui.control.Label
        XNormSwitch                     matlab.ui.control.Switch
        XSwitchLabel                    matlab.ui.control.Label
        AutoRotateButton                matlab.ui.control.Button
        ManualplanerotationPanel        matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        ApplyManualRotateButton         matlab.ui.control.Button
        ResetManualRotationButton       matlab.ui.control.Button
        x_rotationSpinner               matlab.ui.control.Spinner
        x_rotationSpinnerLabel          matlab.ui.control.Label
        y_rotationSpinner               matlab.ui.control.Spinner
        y_rotationSpinnerLabel          matlab.ui.control.Label
        z_rotationSpinner               matlab.ui.control.Spinner
        z_rotationSpinner_2Label        matlab.ui.control.Label
        DepthImgCorrected               matlab.ui.control.UIAxes
        DepthImgView                    matlab.ui.control.UIAxes
        ImageTab                        matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        GreentresholdPanel              matlab.ui.container.Panel
        GridLayout11_2                  matlab.ui.container.GridLayout
        GreenCover                      matlab.ui.control.NumericEditField
        GreenleaveLabel                 matlab.ui.control.Label
        AStarThresholdSpinner           matlab.ui.control.Spinner
        greenthreshholdaLabel           matlab.ui.control.Label
        GreenFilteredImage              matlab.ui.control.UIAxes
        OriginalImage                   matlab.ui.control.UIAxes
        AStarMaskImage                  matlab.ui.control.UIAxes
        LAITab                          matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        HiddenLeavesFactorSpinner       matlab.ui.control.Spinner
        HiddenLeavesFactorSpinnerLabel  matlab.ui.control.Label
        CALCULATEDLEAFAREALabel         matlab.ui.control.Label
        LeafAreaIndexLabel              matlab.ui.control.Label
        WALLAREALabel                   matlab.ui.control.Label
        WallAreamLabel                  matlab.ui.control.Label
        CalculateLeafAreaIndexButton    matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        ExportTab                       matlab.ui.container.Tab
        GetDataButton                   matlab.ui.control.Button
        ExportasDropDown                matlab.ui.control.DropDown
        ExportasDropDownLabel           matlab.ui.control.Label
        LogTab                          matlab.ui.container.Tab
        LogWindowArea                   matlab.ui.control.TextArea
        madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel  matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
    end

    
    properties (Access = private)
        logger              % Logger class
        parameter           % Parameter class
        parameter_data      % The parameter data used in wole the app
        file_path_params    % The parameter path
        
        raw_bag_data      % The data imported from the .bag file untouched
        raw_ply_data      % The data imported from the .bag file untouched
        original_rgb_img  % The original RGB image extracted from the .bag file
        %scaledRGBImg    % RGB image scaled with depth view

        original_depth_img          % The original Depth image extracted from the .ply file
        corrected_depth_img         % The depth image corrrected in dimensions
        rotated_depth_img           % the depth image rotated
        model_depth_img             % Model used to rotate
        
        amount_of_layers            % The amount of layers to seperate the pointcloud
        pointcloud_layers           % an 'amount_of_layers' amount of pointcloud layers

        %sizeOriginalDepthImg   % The width (2) and height(1) data
        %correctedDepthImg         % Depth image cropped with rectangle

        file_path_bag % The path to the .bag file
        file_path_ply % The path to the .ply file
        area_m_square % The given area of the data

        total_height % The total height of the leave deck
        approx_layers % An approximation of the user of the different layers
        
        lab_img      % The full L*a*b* image
        a_star_component % The a* component from the LAB image
        a_star_tresh % The threshold to binarize the a* component
        a_star_mask  % The mask used to determine the grean leaves
        threshed_rgb_img % The RGB image threshed with the a* mask

        log_retrieval_timer 
    end
    
    methods (Access = private)
        
        function ComputeBagData(app, bagData)
            curSelColor = select(bagData,'Topic','/device_0/sensor_1/Color_0/image/data');
            allImColor = readMessages(curSelColor, 'DataFormat', 'struct');
            app.original_rgb_img = double(rosReadImage(allImColor{1}))/255.0;
            app.logger.WriteLog("Done computing bag data");
        end
        
        function DispImg(app, img, parent)
            imshow(img, 'Parent',parent);
            app.logger.WriteLog("Display image");
        end

        function DispImg3d(~, img, parent)
            surf(img, "EdgeColor","none", 'Parent', parent);
            app.logger.WriteLog("Display 3d image");
        end
        
        function ComputePlyData(app, plyData)
            app.original_depth_img = plyData;
            app.logger.WriteLog("Done computing pointcloud data");
        end
        
        function DispPCImg(app, img, parent)
            pcshow(img, 'Parent',parent);
            app.logger.WriteLog("Display pointcloud image");
        end
        
        function cleancloud = SetTotalHeightDepth(app, pointcloud, height)
            app.logger.WriteLog(strcat("Set the total height of the pointcloud: ", num2str(height), "m"));
            [model,inlierIndices, ~] = pcfitplane(pointcloud,height);
            app.model_depth_img = model;
            cleancloud = select(pointcloud, inlierIndices); % Select the indices that are in the total_height region
        end
        
        function rotated_image = RotateDepthImage(app, model, image, reference)
                
            app.logger.WriteLog(strcat("Depth image norm: ", mat2str(app.model_depth_img.Normal)));

            tform = normalRotation(model,reference);

            app.logger.WriteLog(strcat("Rotation matrix: ", mat2str(tform.R)));
                
            rotated_image = pctransform(image,tform);

            app.logger.WriteLog("Done rotating depth image");
        end
        
        function lab_image = CreateLABImage(app, rgb_image)
            lab_image = rgb2lab(rgb_image);
            app.logger.WriteLog("Done converting rgb image to L*a*b*");
        end

        function a_star_component = GetAStarComponent(app, lab_image)
            % L* De intensiteit
                % L gaat van 0 (black) <-> 100 (white)
            % a* de verhouding van groen-rood
                % a gaat van -127 (groen) <-> 128 (rood)
            % b* de verhouding van blauw-geel
                % b gaat van -127 (blauw) <-> 128 (geel)
            a_star_component = lab_image(:,:,2);
            app.logger.WriteLog("Done extracting a* component");
        end
        
        function a_star_binarized = ApplyThresh(app, a_star_comp, threshold)
            a_star_binarized = zeros(size(a_star_comp));
            a_star_binarized(:,:) = (a_star_comp(:,:) < threshold);
            app.logger.WriteLog(strcat("Done tresholding a* component with threshold: ", num2str(threshold)));
        end

        function result = ApplyMaskOnImage(app, image, mask)
            result = bsxfun(@times, image, cast(mask, 'like', image)); 
            app.logger.WriteLog("Done applying mask on image");
        end

        function LogRetrieval(app, src, event)
            app.LogWindowArea.Value = app.logger.GetCurrentLogging;
            scroll(app.LogWindowArea, "bottom");
        end
        
        function results = CalcGreenArea(app, bin_image)

            % Determine the area covered by green leaves with props
            app.logger.WriteLog("Determine the covered green on a pecific binairy image");

            pix_count = sum(bin_image, 'all'); % Count everything where a 1 is.
            app.logger.WriteLog(strcat("Total amount of '1's in image: ", num2str(pix_count)));

            size_img = size(bin_image);
            area_img = size_img(1)*size_img(2);
            app.logger.WriteLog(strcat("Total image points: ", num2str(area_img)));

            norm_amount_green =  pix_count/ area_img; % normalized amount of green
            app.logger.WriteLog(strcat("Found the green percentage: ",num2str(area_img)));

            results = norm_amount_green * 100; % To percentage
            app.logger.WriteLog("Done calculating amount of green in image");
        end

        function set_params(app, data)
            app.AreaofimagemEditField.Value = data.area_of_image;
            app.TotalHeightSpinner.Value = data.total_height_pointcloud;
            % app.ApproxLayersSpinner.Value = data.total_layers_pointcloud;
            app.LayerThicknessSpinner.Value = data.layer_thickness_pointcloud;
            app.ShowLayerSpinner.Value = data.show_layer_pointcloud;
            app.XNormSwitch.Value = data.auto_rotate_norm(1);
            app.YNormSwitch.Value = data.auto_rotate_norm(2);
            app.ZNormSwitch.Value = data.auto_rotate_norm(3);

            app.x_rotationSpinner.Value = data.manual_plane_rotation_norm(1);
            app.y_rotationSpinner.Value = data.manual_plane_rotation_norm(2);
            app.z_rotationSpinner.Value = data.manual_plane_rotation_norm(3);

            app.AStarThresholdSpinner.Value = data.green_threshold_a_star_mask;
            app.HiddenLeavesFactorSpinner.Value = data.hidden_leaves_factor;

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            addpath bin
            app.logger = Logger();

            app.logger.WriteLog("Startup application");
            app.parameter_data.area_of_image = 0;
            app.parameter_data.total_height_pointcloud = 0.2;
            app.parameter_data.total_layers_pointcloud = 2;
            app.parameter_data.layer_thickness_pointcloud = 1;
            app.parameter_data.show_layer_pointcloud = 1;
            app.parameter_data.auto_rotate_norm = [0 0 1];
            app.parameter_data.manual_plane_rotation_norm = [0 0 1];
            app.parameter_data.green_threshold_a_star_mask = 0;
            app.parameter_data.hidden_leaves_factor = 0;
            app.parameter_data.parameter_file = "params\defaultParameters.json";
            app.parameter_data.bag_file = "C:\Users\robel\Documents\GitHub\LAI_detection\High_Coverage\HC_20231130_143832.bag";
            app.parameter_data.pointcloud_file = "C:\Users\robel\Documents\GitHub\LAI_detection\High_Coverage\HC_20231130_143832.ply";
            
            app.parameter = ParameterExtractor("defaultParameters.json", app.parameter_data);

            app.parameter_data = app.parameter.ReadData();
            app.logger.WriteLog(strcat("Define the default parameters from: ",app.parameter_data.parameter_file));
            
            set_params(app, app.parameter_data);

            app.paramFile.Value = app.parameter_data.parameter_file;
            app.plyFile.Value = app.parameter_data.pointcloud_file;
            app.bagFile.Value = app.parameter_data.bag_file;

            app.log_retrieval_timer = timer("ExecutionMode","fixedRate","Period",5, ...
            "BusyMode","queue","TimerFcn",@app.LogRetrieval);

            if strcmp(app.log_retrieval_timer.Running,"off")
                start(app.log_retrieval_timer);
                app.logger.WriteLog("Start log timer");
            end

        end

        % Button pushed function: LoadDataButton
        function LoadFile(app, event)
            % reset the current values
            clc;
            
            app.logger.WriteLog("Loading file");

            %path = "ImgLoad/";
            app.file_path_bag = app.bagFile.Value;
            app.file_path_ply = app.plyFile.Value;
            
            errorFlag = 0;

            try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                    app.raw_bag_data = rosbag(app.file_path_bag);
                    app.raw_ply_data = pcread(app.file_path_ply);

                    app.logger.WriteLog(strcat(".bag file: ", app.file_path_bag));
                    app.logger.WriteLog(strcat("pointcloud file: ", app.file_path_ply));
                end
            catch error_message
                if errorFlag == 0  % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.ErrorLabel.Text = "Something wrong with the given path.";
                    app.logger.WriteLogError( ...
                        strcat("Something wrong with the given path: bagfile=", app.file_path_bag, ", plyfile=", app.file_path_ply), ...
                        error_message.message);
                end
            end

            try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                    app.area_m_square = app.AreaofimagemEditField.Value;
                    % disp(app.area_m_square)
                    app.logger.WriteLog(strcat("given area per m²: ", num2str(app.area_m_square), "m²"));
                end
                
            catch error_message
                if errorFlag == 0  % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.ErrorLabel.Text = "Something wrong with area m²";
                    app.logger.WriteLogError( ...
                        strcat("Something wrong with area m²: ",num2str(app.AreaofimagemEditField.Value)), ...
                        error_message.message);
                end
            end

            try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                    app.logger.WriteLog("Start computing .bag data");
                    ComputeBagData(app, app.raw_bag_data); % Compute the image as rgb and depth image and return this
                end
                
            catch error_message
                if errorFlag == 0 % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.ErrorLabel.Text = "Can't compute .bag data";
                    app.logger.WriteLogError( ...
                        "Can't compute .bag data", ...
                        error_message.message);
                end
            end 

             try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                    app.logger.WriteLog("Start computing .ply data");
                    ComputePlyData(app, app.raw_ply_data); 
                end
             catch error_message
                if errorFlag == 0 % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.logger.WriteLogError( ...
                        "Can't compute .ply data", ...
                        error_message.message);
                end
             end 

             try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                    app.logger.WriteLog("Start importing parameters");
                    app.parameter.setFilePath(app.paramFile.Value);
                    app.parameter.ReadData();

                    set_params(app, app.parameter_data);
                    app.logger.WriteLog("Set the parameters parameters");
                end
             catch error_message
                if errorFlag == 0 % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.logger.WriteLogError( ...
                        "Can't import parameter data", ...
                        error_message.message);
                end
             end 


            % https://www.mathworks.com/matlabcentral/answers/418893-how-to-display-an-image-in-ui-figure-on-app-designer
            if errorFlag == 0
                app.logger.WriteLog("Display images on Import & Examine tab");

                DispImg(app, app.original_rgb_img, app.RGBImg);
                DispPCImg(app, app.original_depth_img, app.DepthImg);

                % set the images on the other tabs

                % Set Examine - Depth
                app.corrected_depth_img = SetTotalHeightDepth(app, app.original_depth_img, app.TotalHeightSpinner.Value);
                DispPCImg(app, app.corrected_depth_img, app.DepthImgCorrected);

                % Set Examine - Image
                DispImg(app, app.original_rgb_img, app.OriginalImage)

                app.lab_img = CreateLABImage(app, app.original_rgb_img);
                app.a_star_component = GetAStarComponent(app, app.lab_img);

                app.a_star_mask = ApplyThresh(app, app.a_star_component, app.AStarThresholdSpinner.Value);
                DispImg(app, app.a_star_mask, app.AStarMaskImage);

                app.threshed_rgb_img = ApplyMaskOnImage(app, app.original_rgb_img, app.a_star_mask);
                DispImg(app, app.threshed_rgb_img, app.GreenFilteredImage);

                app.GreenCover.Value = CalcGreenArea(app, app.a_star_mask);

            end
        end

        % Button pushed function: SearchFileButton
        function SearchFileButtonPushed(app, event)
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html
            try
                app.logger.WriteLog("Search For a file of .bag type");
                [file,path] = uigetfile('*.bag');
                if isequal(file,0)
                    %disp('User selected Cancel');
                    app.logger.WriteLog("Cancel search (.bag)");
                else
                    %disp(['User selected ', fullfile(path,file)]);
                    app.file_path_bag = fullfile(path,file); %Transfer filepath
                    app.bagFile.Value = app.file_path_bag; % set filepath in editable field
                end
                app.logger.WriteLog(strcat("selected path (.bag)", fullfile(path,file)));

            catch error_message
                %disp("something wrong with the file");
                app.logger.WriteLogError( ...
                        "something wrong with the selected path file (.bag)", ...
                        error_message.message);
            end
        end

        % Button pushed function: SearchFileButton_2
        function SearchFileButton_2Pushed(app, event)
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html
            try
                app.logger.WriteLog("Search For a file of .ply type");
                [file,path] = uigetfile('*.ply');
                if isequal(file,0)
                    %disp('User selected Cancel');
                    app.logger.WriteLog("Cancel search (.ply)");
                else
                    %disp(['User selected ', fullfile(path,file)]);
                    app.file_path_ply = fullfile(path,file); %Transfer filepath
                    app.plyFile.Value = app.file_path_ply; % set filepath in editable field
                end
                app.logger.WriteLog(strcat("selected path (.ply)", fullfile(path,file)));
            catch error_message
                %disp("something wrong with the file");
                app.logger.WriteLogError( ...
                        "something wrong with the selected path file (.ply)", ...
                        error_message.message);
            end

        end

        % Callback function: not associated with a component
        function TotalHeightSpinnerValueChanged(app, event)

        end

        % Button pushed function: AutoRotateButton, 
        % ...and 1 other component
        function AutoRotateButtonPushed(app, event)
            % Automatic rotation

            reference_vector = [app.XNormSwitch.Value app.YNormSwitch.Value app.ZNormSwitch.Value];  % x=0, y=0, z=1
            app.logger.WriteLog(strcat("Auto rotate the depth image to norm: ", mat2str(reference_vector)));
            % If the vector is not 0,0,0
            if size(reference_vector(reference_vector(:) == 0),2) < 3
                 % Set the manual rotation numbers correct
                app.x_rotationSpinner.Value = app.XNormSwitch.Value;
                app.y_rotationSpinner.Value = app.YNormSwitch.Value;
                app.z_rotationSpinner.Value = app.ZNormSwitch.Value;

                app.rotated_depth_img = RotateDepthImage(app, app.model_depth_img, app.corrected_depth_img, reference_vector);
                
                DispPCImg(app,app.rotated_depth_img, app.DepthImgCorrected)
            else
                app.logger.WriteLogError( ...
                        "The reference Vector is [0 0 0]", ...
                        mat2str(reference_vector));
            end
           
        end

        % Callback function: not associated with a component
        function ManualRotationSpinnerValueChanging(app, event)

        end

        % Button pushed function: ResetAutoRotationButton
        function ResetAutoRotationButtonPushed(app, event)
            app.logger.WriteLog("Reset the rotation of the depth image.");

            app.XNormSwitch.Value = app.parameter_data.auto_rotate_norm(1);
            app.YNormSwitch.Value = app.parameter_data.auto_rotate_norm(2);
            app.ZNormSwitch.Value = app.parameter_data.auto_rotate_norm(3);

            app.x_rotationSpinner.Value = app.manual_plane_rotation_norm(1);
            app.y_rotationSpinner.Value = app.manual_plane_rotation_norm(1);
            app.z_rotationSpinner.Value = app.manual_plane_rotation_norm(1);

            app.corrected_depth_img = SetTotalHeightDepth(app, app.original_depth_img, app.TotalHeightSpinner.Value);
            DispPCImg(app, app.corrected_depth_img, app.DepthImgCorrected);

            
        end

        % Value changed function: AStarThresholdSpinner
        function AStarThresholdSpinnerValueChanged(app, event)
            value = app.AStarThresholdSpinner.Value;
            % When we alter the threshold, we need to recalculate the
            % threshold value for a* component
            app.logger.WriteLog(strcat("Perform an a* threshold with threshold: ", num2str(value)));
            app.a_star_tresh = value;

            app.lab_img = CreateLABImage(app, app.original_rgb_img);
            app.a_star_component = GetAStarComponent(app, app.lab_img);

            app.a_star_mask = ApplyThresh(app, app.a_star_component, app.a_star_tresh);
            DispImg(app, app.a_star_mask, app.AStarMaskImage);

            app.threshed_rgb_img = ApplyMaskOnImage(app, app.original_rgb_img, app.a_star_mask);
            DispImg(app, app.threshed_rgb_img, app.GreenFilteredImage);

            
            app.GreenCover.Value = CalcGreenArea(app, app.a_star_mask);


        end

        % Button pushed function: SearchFileButton_3
        function SearchFileButton_3Pushed(app, event)
                        % https://www.mathworks.com/help/matlab/ref/uigetfile.html
            try
                app.logger.WriteLog("Search For a file of .json type");
                [file,path] = uigetfile('*.json');
                if isequal(file,0)
                    %disp('User selected Cancel');
                    app.logger.WriteLog("Cancel search (.json)");
                else
                    %disp(['User selected ', fullfile(path,file)]);
                    app.file_path_params = fullfile(path,file); %Transfer filepath
                    app.paramFile.Value = app.file_path_params; % set filepath in editable field
                end
                app.logger.WriteLog(strcat("selected path (.json)", fullfile(path,file)));
            catch error_message
                %disp("something wrong with the file");
                app.logger.WriteLogError( ...
                        "something wrong with the selected path file (.json)", ...
                        error_message.message);
            end

        end

        % Close request function: LAIDetectionUIFigure
        function LAIDetectionUIFigureCloseRequest(app, event)
            stop(app.log_retrieval_timer);
            app.logger.WriteLog("Stopped log retriever timer");
            app.logger.WriteLog("closing app");
            delete(app);
            
        end

        % Button pushed function: ApplyManualRotateButton
        function ApplyManualRotateButtonPushed(app, event)
            x = app.x_rotationSpinner.Value;
            y = app.y_rotationSpinner.Value;
            z = app.z_rotationSpinner.Value;

            reference_vector = [x y z];
            app.logger.WriteLog(strcat("Auto rotate the depth image to norm: ", mat2str(reference_vector)));
            % If the vector is not 0,0,0
            if size(reference_vector(reference_vector(:) == 0),2) < 3
                app.rotated_depth_img = RotateDepthImage(app, app.model_depth_img, app.corrected_depth_img, reference_vector);
                DispPCImg(app,app.rotated_depth_img, app.DepthImgCorrected)
            else
                app.logger.WriteLogError( ...
                        "The reference Vector is [0 0 0]", ...
                        mat2str(reference_vector));
            end

        end

        % Button pushed function: ApplyLayerDefButton
        function ApplyLayerDefButtonPushed(app, event)
            value = app.TotalHeightSpinner.Value;
            app.logger.WriteLog(strcat("Change the total height value to: ", num2str(value)));
            
            % change the total thickness. This will cut out outliners
            app.total_height =value;
            app.corrected_depth_img = SetTotalHeightDepth(app, app.original_depth_img, app.total_height);
            
            DispPCImg(app,app.corrected_depth_img, app.DepthImgCorrected);
        end

        % Value changed function: ShowLayerSpinner
        function ShowLayerSpinnerValueChanged(app, event)
            value = app.ShowLayerSpinner.Value;
            
            % Depending on the value, show another layer in the
            % app.DepthImgView

            % Use the calculated layers in the ApplyLayerDefButton
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LAIDetectionUIFigure = uifigure('Visible', 'off');
            app.LAIDetectionUIFigure.Name = 'LAI Detection';
            app.LAIDetectionUIFigure.CloseRequestFcn = createCallbackFcn(app, @LAIDetectionUIFigureCloseRequest, true);
            app.GridLayout4 = uigridlayout(app.LAIDetectionUIFigure);
            app.Hyperlink = uihyperlink(app.GridLayout4);
            app.Hyperlink.URL = 'https://github.com/RobbeElsermans/LAI_detection';
            app.Hyperlink.Text = 'GitHub';
            app.madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel = uilabel(app.GridLayout4);
            app.madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel.Text = 'made by: Robbe Elsermans, Thomas Kramp and Ahmad Shakleya';
            app.TabGroup = uitabgroup(app.GridLayout4);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Tooltip = {'Import data to work with.'};
            app.ImportTab.Title = 'Import';
            app.GridLayout = uigridlayout(app.ImportTab);
            app.DepthImg = uiaxes(app.GridLayout);
            title(app.DepthImg, 'Depth')
            app.DepthImg.XTick = [];
            app.DepthImg.YTick = [];
            app.RGBImg = uiaxes(app.GridLayout);
            title(app.RGBImg, 'RGB')
            app.RGBImg.XTick = [];
            app.RGBImg.YTick = [];
            app.ImportfilesPanel = uipanel(app.GridLayout);
            app.ImportfilesPanel.Title = 'Import files';
            app.GridLayout9 = uigridlayout(app.ImportfilesPanel);
            app.content2 = uilabel(app.GridLayout9);
            app.content2.Text = 'Path';
            app.bagFile = uieditfield(app.GridLayout9, 'text');
            app.bagFile.Placeholder = 'c:/img1.bag';
            app.content2_2 = uilabel(app.GridLayout9);
            app.content2_2.Text = 'Path';
            app.plyFile = uieditfield(app.GridLayout9, 'text');
            app.plyFile.Placeholder = 'c:/img1.ply';
            app.SearchFileButton = uibutton(app.GridLayout9, 'push');
            app.SearchFileButton.ButtonPushedFcn = createCallbackFcn(app, @SearchFileButtonPushed, true);
            app.SearchFileButton.Text = 'Search File';
            app.SearchFileButton_2 = uibutton(app.GridLayout9, 'push');
            app.SearchFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SearchFileButton_2Pushed, true);
            app.SearchFileButton_2.Text = 'Search File';
            app.AreaofimagemEditFieldLabel = uilabel(app.GridLayout9);
            app.AreaofimagemEditFieldLabel.Text = 'Area of image (m²)';
            app.AreaofimagemEditField = uieditfield(app.GridLayout9, 'numeric');
            app.AreaofimagemEditField.Limits = [0 Inf];
            app.AreaofimagemEditField.ValueDisplayFormat = '%0.0f m²';
            app.AreaofimagemEditField.Placeholder = 'm²';
            app.LoadDataButton = uibutton(app.GridLayout9, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFile, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ErrorLabel = uilabel(app.GridLayout9);
            app.ErrorLabel.Tag = 'FileImportError';
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Error';
            app.ParametersLabel = uilabel(app.GridLayout9);
            app.ParametersLabel.Text = 'Parameters';
            app.paramFile = uieditfield(app.GridLayout9, 'text');
            app.paramFile.Placeholder = 'defaultParameters.json';
            app.SearchFileButton_3 = uibutton(app.GridLayout9, 'push');
            app.SearchFileButton_3.ButtonPushedFcn = createCallbackFcn(app, @SearchFileButton_3Pushed, true);
            app.SearchFileButton_3.Text = 'Search File';
            app.ExamineTab = uitab(app.TabGroup);
            app.ExamineTab.Tooltip = {''};
            app.ExamineTab.Title = 'Examine';
            app.TabGroup3 = uitabgroup(app.ExamineTab);
            app.DepthTab = uitab(app.TabGroup3);
            app.DepthTab.Tooltip = {''};
            app.DepthTab.Title = 'Depth';
            app.GridLayout5 = uigridlayout(app.DepthTab);
            app.DepthImgView = uiaxes(app.GridLayout5);
            title(app.DepthImgView, 'Point Cloud Layer')
            xlabel(app.DepthImgView, 'X')
            ylabel(app.DepthImgView, 'Y')
            zlabel(app.DepthImgView, 'Z')
            app.DepthImgCorrected = uiaxes(app.GridLayout5);
            title(app.DepthImgCorrected, 'Point Cloud corrected')
            xlabel(app.DepthImgCorrected, 'X')
            ylabel(app.DepthImgCorrected, 'Y')
            zlabel(app.DepthImgCorrected, 'Z')
            app.ManualplanerotationPanel = uipanel(app.GridLayout5);
            app.ManualplanerotationPanel.Title = 'Manual plane rotation';
            app.GridLayout7 = uigridlayout(app.ManualplanerotationPanel);
            app.z_rotationSpinner_2Label = uilabel(app.GridLayout7);
            app.z_rotationSpinner_2Label.Text = 'z_rotation';
            app.z_rotationSpinner = uispinner(app.GridLayout7);
            app.z_rotationSpinner.Step = 0.01;
            app.z_rotationSpinner.Limits = [-1 1];
            app.z_rotationSpinner.ValueDisplayFormat = '%.4f';
            app.y_rotationSpinnerLabel = uilabel(app.GridLayout7);
            app.y_rotationSpinnerLabel.Text = 'y_rotation';
            app.y_rotationSpinner = uispinner(app.GridLayout7);
            app.y_rotationSpinner.Step = 0.01;
            app.y_rotationSpinner.Limits = [-1 1];
            app.y_rotationSpinner.ValueDisplayFormat = '%.4f';
            app.x_rotationSpinnerLabel = uilabel(app.GridLayout7);
            app.x_rotationSpinnerLabel.Text = 'x_rotation';
            app.x_rotationSpinner = uispinner(app.GridLayout7);
            app.x_rotationSpinner.Step = 0.01;
            app.x_rotationSpinner.Limits = [-1 1];
            app.x_rotationSpinner.ValueDisplayFormat = '%.4f';
            app.ResetManualRotationButton = uibutton(app.GridLayout7, 'push');
            app.ResetManualRotationButton.ButtonPushedFcn = createCallbackFcn(app, @AutoRotateButtonPushed, true);
            app.ResetManualRotationButton.Text = 'Reset';
            app.ApplyManualRotateButton = uibutton(app.GridLayout7, 'push');
            app.ApplyManualRotateButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyManualRotateButtonPushed, true);
            app.ApplyManualRotateButton.Text = 'Apply';
            app.AutoplanerotationPanel = uipanel(app.GridLayout5);
            app.AutoplanerotationPanel.Title = 'Auto plane rotation';
            app.GridLayout8 = uigridlayout(app.AutoplanerotationPanel);
            app.AutoRotateButton = uibutton(app.GridLayout8, 'push');
            app.AutoRotateButton.ButtonPushedFcn = createCallbackFcn(app, @AutoRotateButtonPushed, true);
            app.AutoRotateButton.Text = 'Auto Rotate';
            app.XSwitchLabel = uilabel(app.GridLayout8);
            app.XSwitchLabel.Text = 'X';
            app.XNormSwitch = uiswitch(app.GridLayout8, 'slider');
            app.XNormSwitch.Items = {'0', '1'};
            app.XNormSwitch.ItemsData = [0 1];
            app.XNormSwitch.Value = 0;
            app.YSwitchLabel = uilabel(app.GridLayout8);
            app.YSwitchLabel.Text = 'Y';
            app.YNormSwitch = uiswitch(app.GridLayout8, 'slider');
            app.YNormSwitch.Items = {'0', '1'};
            app.YNormSwitch.ItemsData = [0 1];
            app.YNormSwitch.Value = 0;
            app.ZSwitchLabel = uilabel(app.GridLayout8);
            app.ZSwitchLabel.Text = 'Z';
            app.ZNormSwitch = uiswitch(app.GridLayout8, 'slider');
            app.ZNormSwitch.Items = {'0', '1'};
            app.ZNormSwitch.ItemsData = [0 1];
            app.ZNormSwitch.Value = 0;
            app.ResetAutoRotationButton = uibutton(app.GridLayout8, 'push');
            app.ResetAutoRotationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAutoRotationButtonPushed, true);
            app.ResetAutoRotationButton.Text = 'Reset';
            app.LayerdefinitionPanel = uipanel(app.GridLayout5);
            app.LayerdefinitionPanel.Title = 'Layer definition';
            app.GridLayout10 = uigridlayout(app.LayerdefinitionPanel);
            app.ShowLayerSpinnerLabel = uilabel(app.GridLayout10);
            app.ShowLayerSpinnerLabel.Text = 'Show Layer';
            app.ShowLayerSpinner = uispinner(app.GridLayout10);
            app.ShowLayerSpinner.Limits = [0 Inf];
            app.ShowLayerSpinner.ValueChangedFcn = createCallbackFcn(app, @ShowLayerSpinnerValueChanged, true);
            app.ResetLayerHeightButton = uibutton(app.GridLayout10, 'push');
            app.ResetLayerHeightButton.Text = 'Reset';
            app.LayerThicknessmSpinnerLabel = uilabel(app.GridLayout10);
            app.LayerThicknessmSpinnerLabel.Text = 'Layer Thickness (m)';
            app.LayerThicknessSpinner = uispinner(app.GridLayout10);
            app.LayerThicknessSpinner.Step = 0.01;
            app.LayerThicknessSpinner.Limits = [0 Inf];
            app.TotalHeightmSpinnerLabel = uilabel(app.GridLayout10);
            app.TotalHeightmSpinnerLabel.Text = 'Total Height (m)';
            app.TotalHeightSpinner = uispinner(app.GridLayout10);
            app.TotalHeightSpinner.Step = 0.01;
            app.TotalHeightSpinner.Limits = [0 Inf];
            app.TotalHeightSpinner.Value = 0.2;
            app.ApplyLayerDefButton = uibutton(app.GridLayout10, 'push');
            app.ApplyLayerDefButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyLayerDefButtonPushed, true);
            app.ApplyLayerDefButton.Text = 'Apply';
            app.DepthInfo = uitextarea(app.GridLayout5);
            app.ImageTab = uitab(app.TabGroup3);
            app.ImageTab.Title = 'Image';
            app.GridLayout6 = uigridlayout(app.ImageTab);
            app.AStarMaskImage = uiaxes(app.GridLayout6);
            title(app.AStarMaskImage, 'a* mask')
            app.AStarMaskImage.XTick = [];
            app.AStarMaskImage.YTick = [];
            app.OriginalImage = uiaxes(app.GridLayout6);
            title(app.OriginalImage, 'Original Image')
            app.OriginalImage.XTick = [];
            app.OriginalImage.YTick = [];
            app.GreenFilteredImage = uiaxes(app.GridLayout6);
            title(app.GreenFilteredImage, 'Original Image with a* mask')
            app.GreenFilteredImage.XTick = [];
            app.GreenFilteredImage.YTick = [];
            app.GreentresholdPanel = uipanel(app.GridLayout6);
            app.GreentresholdPanel.Title = 'Green treshold';
            app.GridLayout11_2 = uigridlayout(app.GreentresholdPanel);
            app.greenthreshholdaLabel = uilabel(app.GridLayout11_2);
            app.greenthreshholdaLabel.Text = 'green threshhold (a*)';
            app.AStarThresholdSpinner = uispinner(app.GridLayout11_2);
            app.AStarThresholdSpinner.Step = 0.1;
            app.AStarThresholdSpinner.Limits = [-127 128];
            app.AStarThresholdSpinner.ValueDisplayFormat = '%.2f';
            app.AStarThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @AStarThresholdSpinnerValueChanged, true);
            app.GreenleaveLabel = uilabel(app.GridLayout11_2);
            app.GreenleaveLabel.Text = 'Green leave %';
            app.GreenCover = uieditfield(app.GridLayout11_2, 'numeric');
            app.GreenCover.Editable = 'off';
            app.LAITab = uitab(app.TabGroup3);
            app.LAITab.Tooltip = {'Compute Leave Area Index and otpion to finetune the used equasions.'};
            app.LAITab.Title = 'LAI';
            app.GridLayout11 = uigridlayout(app.LAITab);
            app.UIAxes = uiaxes(app.GridLayout11);
            title(app.UIAxes, 'Merged images')
            app.CalculateLeafAreaIndexButton = uibutton(app.GridLayout11, 'push');
            app.CalculateLeafAreaIndexButton.Text = 'Calculate Leaf  Area Index';
            app.WallAreamLabel = uilabel(app.GridLayout11);
            app.WallAreamLabel.Text = 'Wall Area (m²)';
            app.WALLAREALabel = uilabel(app.GridLayout11);
            app.WALLAREALabel.Text = 'WALL AREA';
            app.LeafAreaIndexLabel = uilabel(app.GridLayout11);
            app.LeafAreaIndexLabel.Text = 'Leaf Area Index';
            app.CALCULATEDLEAFAREALabel = uilabel(app.GridLayout11);
            app.CALCULATEDLEAFAREALabel.Text = 'CALCULATED LEAF AREA';
            app.HiddenLeavesFactorSpinnerLabel = uilabel(app.GridLayout11);
            app.HiddenLeavesFactorSpinnerLabel.Text = 'Hidden Leaves Factor';
            app.HiddenLeavesFactorSpinner = uispinner(app.GridLayout11);
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Tooltip = {'Export the data'};
            app.ExportTab.Title = 'Export';
            app.ExportasDropDownLabel = uilabel(app.ExportTab);
            app.ExportasDropDownLabel.Text = 'Export as';
            app.ExportasDropDown = uidropdown(app.ExportTab);
            app.ExportasDropDown.Items = {'LAI with parameters', 'RGB Image (.jpg)', 'RGB slices (.jpg)', 'Parameters'};
            app.ExportasDropDown.Value = 'Parameters';
            app.GetDataButton = uibutton(app.ExportTab, 'push');
            app.GetDataButton.Text = 'Get Data';
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.LogWindowArea = uitextarea(app.LogTab);
            % Show the figure after all components are created
            app.LAIDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LAI_calc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LAIDetectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LAIDetectionUIFigure)
        end
    end
end