classdef testCase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LAIDetectionUIFigure         matlab.ui.Figure
        GridLayout4                  matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        ImportTab                    matlab.ui.container.Tab
        GridLayout                   matlab.ui.container.GridLayout
        ImportfilesPanel             matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        ErrorLabel                   matlab.ui.control.Label
        LoadDataButton               matlab.ui.control.Button
        AreaofimagemEditField        matlab.ui.control.NumericEditField
        AreaofimagemEditFieldLabel   matlab.ui.control.Label
        SearchFileButton_2           matlab.ui.control.Button
        SearchFileButton             matlab.ui.control.Button
        plyFile                      matlab.ui.control.EditField
        content2_2                   matlab.ui.control.Label
        bagFile                      matlab.ui.control.EditField
        content2                     matlab.ui.control.Label
        DepthImg                     matlab.ui.control.UIAxes
        RGBImg                       matlab.ui.control.UIAxes
        ExamineTab                   matlab.ui.container.Tab
        TabGroup3                    matlab.ui.container.TabGroup
        DepthTab                     matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        DepthInfo                    matlab.ui.control.TextArea
        LayerdefinitionPanel         matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        ApproxLayersSpinner          matlab.ui.control.Spinner
        ApproxLayersLabel            matlab.ui.control.Label
        TotalHeightSpinner           matlab.ui.control.Spinner
        TotalHeightmSpinnerLabel     matlab.ui.control.Label
        LayerThicknessSpinner        matlab.ui.control.Spinner
        LayerThicknessmSpinnerLabel  matlab.ui.control.Label
        ResetLayerHeightButton       matlab.ui.control.Button
        ShowLayerSpinner             matlab.ui.control.Spinner
        ShowLayerSpinnerLabel        matlab.ui.control.Label
        AutoplanerotationPanel       matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        ResetAutoRotationButton      matlab.ui.control.Button
        ZNormSwitch                  matlab.ui.control.Switch
        ZSwitchLabel                 matlab.ui.control.Label
        YNormSwitch                  matlab.ui.control.Switch
        YSwitchLabel                 matlab.ui.control.Label
        XNormSwitch                  matlab.ui.control.Switch
        XSwitchLabel                 matlab.ui.control.Label
        AutoRotateButton             matlab.ui.control.Button
        ManualplanerotationPanel     matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        ResetManualRotationButton    matlab.ui.control.Button
        x_rotationSpinner            matlab.ui.control.Spinner
        x_rotationSpinnerLabel       matlab.ui.control.Label
        y_rotationSpinner            matlab.ui.control.Spinner
        y_rotationSpinnerLabel       matlab.ui.control.Label
        z_rotationSpinner            matlab.ui.control.Spinner
        z_rotationSpinner_2Label     matlab.ui.control.Label
        DepthImgView                 matlab.ui.control.UIAxes
        DepthImgCorrected            matlab.ui.control.UIAxes
        ImageTab                     matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        GreendetectionPanel          matlab.ui.container.Panel
        LAITab                       matlab.ui.container.Tab
        ExportTab                    matlab.ui.container.Tab
        GetDataButton                matlab.ui.control.Button
        ExportasDropDown             matlab.ui.control.DropDown
        ExportasDropDownLabel        matlab.ui.control.Label
        LogTab                       matlab.ui.container.Tab
        Button2                      matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel  matlab.ui.control.Label
        Hyperlink                    matlab.ui.control.Hyperlink
    end

    
    properties (Access = private)
        oldpath = addpath('bin')
        logger = Logger()

        
        raw_bag_data      % The data imported from the .bag file untouched
        raw_ply_data      % The data imported from the .bag file untouched
        original_rgb_img  % The original RGB image extracted from the .bag file
        %scaledRGBImg    % RGB image scaled with depth view

        original_depth_img          % The original Depth image extracted from the .ply file
        corrected_depth_img         % The depth image corrrected in dimensions
        rotated_depth_img           % the depth image rotated
        model_depth_img             % Model used to rotate
        %sizeOriginalDepthImg   % The width (2) and height(1) data
        %correctedDepthImg         % Depth image cropped with rectangle

        file_path_bag % The path to the .bag file
        file_path_ply % The path to the .ply file
        area_m_square % The given area of the data

        total_height % The total height of the leave deck
        approx_layers % An approximation of the user of the different layers
        
    end
    
    methods (Access = private)
        
        function ComputeBagData(app, bagData)
            curSelColor = select(bagData,'Topic','/device_0/sensor_1/Color_0/image/data');
            allImColor = readMessages(curSelColor, 'DataFormat', 'struct');
            app.original_rgb_img = double(rosReadImage(allImColor{1}))/255.0;
            app.logger.WriteLog("Done cpmputing bag data");
        end
        
        function DispImg(app, img, parent)
            imshow(img, 'Parent',parent);
            app.logger.WriteLog("Display image");
        end

        function DispImg3d(~, img, parent)
            surf(img, "EdgeColor","none", 'Parent', parent);
            app.logger.WriteLog("Display 3d image");
        end
        
        function ComputePlyData(app, plyData)
            app.original_depth_img = plyData;
            app.logger.WriteLog("Done computing pointcloud data");
        end
        
        function DispPCImg(app, img, parent)
            pcshow(img, 'Parent',parent);
            app.logger.WriteLog("Display pointcloud image");
        end
        
        function cleancloud = SetTotalHeightDepth(app, pointcloud, height)
            app.logger.WriteLog(strcat("Set the total height of the pointcloud: ", num2str(height), "m"));
            [model,inlierIndices, ~] = pcfitplane(pointcloud,height);
            app.model_depth_img = model;
            cleancloud = select(pointcloud, inlierIndices); % Select the indices that are in the total_height region
        end
        
        function rotated_image = RotateDepthImage(app, model, image, reference)
                
            app.logger.WriteLog(strcat("Depth image norm: ", mat2str(app.model_depth_img.Normal)));

            tform = normalRotation(model,reference);

            app.logger.WriteLog(strcat("Rotation matrix: ", mat2str(tform.R)));
                
            rotated_image = pctransform(image,tform);

            app.logger.WriteLog("Done rotating depth image");
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadFile(app, event)
            % reset the current values
            clc;
            
            app.logger.WriteLog("Loading file")

            %path = "ImgLoad/";
            app.file_path_bag = app.bagFile.Value;
            app.file_path_ply = app.plyFile.Value;
            
            errorFlag = 0;

            try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                    app.raw_bag_data = rosbag(app.file_path_bag);
                    app.raw_ply_data = pcread(app.file_path_ply);

                    app.logger.WriteLog(strcat(".bag file: ", app.file_path_bag));
                    app.logger.WriteLog(strcat("pointcloud file: ", app.file_path_ply));
                end
            catch error_message
                if errorFlag == 0  % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.ErrorLabel.Text = "Something wrong with the given path.";
                    app.logger.WriteLogError( ...
                        strcat("Something wrong with the given path: bagfile=", app.file_path_bag, ", plyfile=", app.file_path_ply), ...
                        error_message.message);
                end
            end

            try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                    app.area_m_square = app.AreaofimagemEditField.Value;
                    % disp(app.area_m_square)
                    app.logger.WriteLog(strcat("given area per m²: ", num2str(app.area_m_square), "m²"));
                end
                
            catch error_message
                if errorFlag == 0  % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.ErrorLabel.Text = "Something wrong with area m²";
                    app.logger.WriteLogError( ...
                        strcat("Something wrong with area m²: ",num2str(app.AreaofimagemEditField.Value)), ...
                        error_message.message);
                end
            end

            try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                    app.logger.WriteLog("Start computing .bag data");
                    ComputeBagData(app, app.raw_bag_data); % Compute the image as rgb and depth image and return this
                end
                
            catch error_message
                if errorFlag == 0 % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.ErrorLabel.Text = "Can't compute .bag data";
                    app.logger.WriteLogError( ...
                        "Can't compute .bag data", ...
                        error_message.message);
                end
            end 

             try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                    app.logger.WriteLog("Start computing .ply data");
                    ComputePlyData(app, app.raw_ply_data); 
                end
             catch error_message
                if errorFlag == 0 % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.logger.WriteLogError( ...
                        "Can't compute .ply data", ...
                        error_message.message);
                end
             end 

            % https://www.mathworks.com/matlabcentral/answers/418893-how-to-display-an-image-in-ui-figure-on-app-designer
            if errorFlag == 0
                app.logger.WriteLog("Display images on Import & Examine tab");

                DispImg(app, app.original_rgb_img, app.RGBImg);
                DispPCImg(app, app.original_depth_img, app.DepthImg);

                % set the images on the other tabs

                % Set Examine - Depth
                app.corrected_depth_img = SetTotalHeightDepth(app, app.original_depth_img, app.TotalHeightSpinner.Value);
                DispPCImg(app, app.corrected_depth_img, app.DepthImgCorrected);
            end
        end

        % Button pushed function: SearchFileButton
        function SearchFileButtonPushed(app, event)
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html
            try
                app.logger.WriteLog("Search For a file of .bag type");
                [file,path] = uigetfile('*.bag');
                if isequal(file,0)
                    %disp('User selected Cancel');
                    app.logger.WriteLog("Cancel search (.bag)");
                else
                    %disp(['User selected ', fullfile(path,file)]);
                    app.file_path_bag = fullfile(path,file); %Transfer filepath
                    app.bagFile.Value = app.file_path_bag; % set filepath in editable field
                end
                app.logger.WriteLog(strcat("selected path (.bag)", fullfile(path,file)));

            catch error_message
                %disp("something wrong with the file");
                app.logger.WriteLogError( ...
                        "something wrong with the selected path file (.bag)", ...
                        error_message.message);
            end
        end

        % Button pushed function: SearchFileButton_2
        function SearchFileButton_2Pushed(app, event)
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html
            try
                app.logger.WriteLog("Search For a file of .ply type");
                [file,path] = uigetfile('*.ply');
                if isequal(file,0)
                    %disp('User selected Cancel');
                    app.logger.WriteLog("Cancel search (.ply)");
                else
                    %disp(['User selected ', fullfile(path,file)]);
                    app.file_path_ply = fullfile(path,file); %Transfer filepath
                    app.plyFile.Value = app.file_path_ply; % set filepath in editable field
                end
                app.logger.WriteLog(strcat("selected path (.ply)", fullfile(path,file)));
            catch
                %disp("something wrong with the file");
                app.logger.WriteLogError( ...
                        "something wrong with the selected path file (.ply)", ...
                        error_message.message);
            end

        end

        % Value changed function: TotalHeightSpinner
        function TotalHeightSpinnerValueChanged(app, event)
            value = app.TotalHeightSpinner.Value;
            app.logger.WriteLog(strcat("Change the total height value to: ", num2str(value)));

            % change the total thickness. This will cut out outliners
            app.total_height =value;
            app.corrected_depth_img = SetTotalHeightDepth(app, app.original_depth_img, app.total_height);
            
            DispPCImg(app,app.corrected_depth_img, app.DepthImgCorrected);
        end

        % Button pushed function: AutoRotateButton, 
        % ...and 1 other component
        function AutoRotateButtonPushed(app, event)
            % Automatic rotation

            reference_vector = [app.XNormSwitch.Value app.YNormSwitch.Value app.ZNormSwitch.Value];  % x=0, y=0, z=1
            app.logger.WriteLog(strcat("Auto rotate the depth image to norm: ", mat2str(reference_vector)));
            % If the vector is not 0,0,0
            if size(reference_vector(reference_vector(:) == 0),2) < 3
                 % Set the manual rotation numbers correct
                app.x_rotationSpinner.Value = app.XNormSwitch.Value;
                app.y_rotationSpinner.Value = app.YNormSwitch.Value;
                app.z_rotationSpinner.Value = app.ZNormSwitch.Value;

                app.rotated_depth_img = RotateDepthImage(app, app.model_depth_img, app.corrected_depth_img, reference_vector);
                
                DispPCImg(app,app.rotated_depth_img, app.DepthImgCorrected)
            else
                app.logger.WriteLogError( ...
                        "The reference Vector is [0 0 0]", ...
                        mat2str(reference_vector));
            end
           
        end

        % Callback function: x_rotationSpinner, y_rotationSpinner, 
        % ...and 1 other component
        function ManualRotationSpinnerValueChanging(app, event)
            x = app.x_rotationSpinner.Value;
            y = app.y_rotationSpinner.Value;
            z = app.z_rotationSpinner.Value;

            reference_vector = [x y z];
            app.logger.WriteLog(strcat("Auto rotate the depth image to norm: ", mat2str(reference_vector)));
            % If the vector is not 0,0,0
            if size(reference_vector(reference_vector(:) == 0),2) < 3
                app.rotated_depth_img = RotateDepthImage(app, app.model_depth_img, app.corrected_depth_img, reference_vector);
                DispPCImg(app,app.rotated_depth_img, app.DepthImgCorrected)
            else
                app.logger.WriteLogError( ...
                        "The reference Vector is [0 0 0]", ...
                        mat2str(reference_vector));
            end

        end

        % Button pushed function: ResetAutoRotationButton
        function ResetAutoRotationButtonPushed(app, event)
            app.logger.WriteLog("Reset the rotation of the depth image.");

            app.XNormSwitch.Value = 0;
            app.YNormSwitch.Value = 0;
            app.ZNormSwitch.Value = 0;

            app.x_rotationSpinner.Value = 0;
            app.y_rotationSpinner.Value = 0;
            app.z_rotationSpinner.Value = 0;

            app.corrected_depth_img = SetTotalHeightDepth(app, app.original_depth_img, app.TotalHeightSpinner.Value);
            DispPCImg(app, app.corrected_depth_img, app.DepthImgCorrected);

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LAIDetectionUIFigure = uifigure('Visible', 'off');
            app.LAIDetectionUIFigure.Name = 'LAI Detection';
            app.GridLayout4 = uigridlayout(app.LAIDetectionUIFigure);
            app.Hyperlink = uihyperlink(app.GridLayout4);
            app.Hyperlink.URL = 'https://github.com/RobbeElsermans/LAI_detection';
            app.Hyperlink.Text = 'GitHub';
            app.madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel = uilabel(app.GridLayout4);
            app.madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel.Text = 'made by: Robbe Elsermans, Thomas Kramp and Ahmad Shakleya';
            app.TabGroup = uitabgroup(app.GridLayout4);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Tooltip = {'Import data to work with.'};
            app.ImportTab.Title = 'Import';
            app.GridLayout = uigridlayout(app.ImportTab);
            app.RGBImg = uiaxes(app.GridLayout);
            title(app.RGBImg, 'RGB')
            app.RGBImg.XTick = [];
            app.RGBImg.YTick = [];
            app.DepthImg = uiaxes(app.GridLayout);
            title(app.DepthImg, 'Depth')
            app.DepthImg.XTick = [];
            app.DepthImg.YTick = [];
            app.ImportfilesPanel = uipanel(app.GridLayout);
            app.ImportfilesPanel.Title = 'Import files';
            app.GridLayout9 = uigridlayout(app.ImportfilesPanel);
            app.content2 = uilabel(app.GridLayout9);
            app.content2.Text = 'Path';
            app.bagFile = uieditfield(app.GridLayout9, 'text');
            app.bagFile.Placeholder = 'c:/img1.bat';
            app.bagFile.Value = 'C:\Users\robel\Documents\GitHub\LAI_detection\High_Coverage\HC_20231130_143832.bag';
            app.content2_2 = uilabel(app.GridLayout9);
            app.content2_2.Text = 'Path';
            app.plyFile = uieditfield(app.GridLayout9, 'text');
            app.plyFile.Placeholder = 'c:/img1.ply';
            app.plyFile.Value = 'C:\Users\robel\Documents\GitHub\LAI_detection\High_Coverage\HC_20231130_143832.ply';
            app.SearchFileButton = uibutton(app.GridLayout9, 'push');
            app.SearchFileButton.ButtonPushedFcn = createCallbackFcn(app, @SearchFileButtonPushed, true);
            app.SearchFileButton.Text = 'Search File';
            app.SearchFileButton_2 = uibutton(app.GridLayout9, 'push');
            app.SearchFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SearchFileButton_2Pushed, true);
            app.SearchFileButton_2.Text = 'Search File';
            app.AreaofimagemEditFieldLabel = uilabel(app.GridLayout9);
            app.AreaofimagemEditFieldLabel.Text = 'Area of image (m²)';
            app.AreaofimagemEditField = uieditfield(app.GridLayout9, 'numeric');
            app.AreaofimagemEditField.Limits = [0 Inf];
            app.AreaofimagemEditField.ValueDisplayFormat = '%0.0f m²';
            app.AreaofimagemEditField.Placeholder = 'm²';
            app.LoadDataButton = uibutton(app.GridLayout9, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFile, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ErrorLabel = uilabel(app.GridLayout9);
            app.ErrorLabel.Tag = 'FileImportError';
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Error';
            app.ExamineTab = uitab(app.TabGroup);
            app.ExamineTab.Tooltip = {''};
            app.ExamineTab.Title = 'Examine';
            app.TabGroup3 = uitabgroup(app.ExamineTab);
            app.DepthTab = uitab(app.TabGroup3);
            app.DepthTab.Tooltip = {''};
            app.DepthTab.Title = 'Depth';
            app.GridLayout5 = uigridlayout(app.DepthTab);
            app.DepthImgCorrected = uiaxes(app.GridLayout5);
            title(app.DepthImgCorrected, 'Point Cloud corrected')
            xlabel(app.DepthImgCorrected, 'X')
            ylabel(app.DepthImgCorrected, 'Y')
            zlabel(app.DepthImgCorrected, 'Z')
            app.DepthImgView = uiaxes(app.GridLayout5);
            title(app.DepthImgView, 'Point Cloud Layer')
            xlabel(app.DepthImgView, 'X')
            ylabel(app.DepthImgView, 'Y')
            zlabel(app.DepthImgView, 'Z')
            app.ManualplanerotationPanel = uipanel(app.GridLayout5);
            app.ManualplanerotationPanel.Title = 'Manual plane rotation';
            app.GridLayout7 = uigridlayout(app.ManualplanerotationPanel);
            app.z_rotationSpinner_2Label = uilabel(app.GridLayout7);
            app.z_rotationSpinner_2Label.Text = 'z_rotation';
            app.z_rotationSpinner = uispinner(app.GridLayout7);
            app.z_rotationSpinner.Step = 0.01;
            app.z_rotationSpinner.Limits = [-1 1];
            app.z_rotationSpinner.ValueDisplayFormat = '%.4f';
            app.z_rotationSpinner.ValueChangedFcn = createCallbackFcn(app, @ManualRotationSpinnerValueChanging, true);
            app.y_rotationSpinnerLabel = uilabel(app.GridLayout7);
            app.y_rotationSpinnerLabel.Text = 'y_rotation';
            app.y_rotationSpinner = uispinner(app.GridLayout7);
            app.y_rotationSpinner.Step = 0.01;
            app.y_rotationSpinner.Limits = [-1 1];
            app.y_rotationSpinner.ValueDisplayFormat = '%.4f';
            app.y_rotationSpinner.ValueChangedFcn = createCallbackFcn(app, @ManualRotationSpinnerValueChanging, true);
            app.x_rotationSpinnerLabel = uilabel(app.GridLayout7);
            app.x_rotationSpinnerLabel.Text = 'x_rotation';
            app.x_rotationSpinner = uispinner(app.GridLayout7);
            app.x_rotationSpinner.Step = 0.01;
            app.x_rotationSpinner.ValueChangingFcn = createCallbackFcn(app, @ManualRotationSpinnerValueChanging, true);
            app.x_rotationSpinner.Limits = [-1 1];
            app.x_rotationSpinner.ValueDisplayFormat = '%.4f';
            app.ResetManualRotationButton = uibutton(app.GridLayout7, 'push');
            app.ResetManualRotationButton.ButtonPushedFcn = createCallbackFcn(app, @AutoRotateButtonPushed, true);
            app.ResetManualRotationButton.Text = 'Reset';
            app.AutoplanerotationPanel = uipanel(app.GridLayout5);
            app.AutoplanerotationPanel.Title = 'Auto plane rotation';
            app.GridLayout8 = uigridlayout(app.AutoplanerotationPanel);
            app.AutoRotateButton = uibutton(app.GridLayout8, 'push');
            app.AutoRotateButton.ButtonPushedFcn = createCallbackFcn(app, @AutoRotateButtonPushed, true);
            app.AutoRotateButton.Text = 'Auto Rotate';
            app.XSwitchLabel = uilabel(app.GridLayout8);
            app.XSwitchLabel.Text = 'X';
            app.XNormSwitch = uiswitch(app.GridLayout8, 'slider');
            app.XNormSwitch.Items = {'0', '1'};
            app.XNormSwitch.ItemsData = [0 1];
            app.XNormSwitch.Value = 0;
            app.YSwitchLabel = uilabel(app.GridLayout8);
            app.YSwitchLabel.Text = 'Y';
            app.YNormSwitch = uiswitch(app.GridLayout8, 'slider');
            app.YNormSwitch.Items = {'0', '1'};
            app.YNormSwitch.ItemsData = [0 1];
            app.YNormSwitch.Value = 0;
            app.ZSwitchLabel = uilabel(app.GridLayout8);
            app.ZSwitchLabel.Text = 'Z';
            app.ZNormSwitch = uiswitch(app.GridLayout8, 'slider');
            app.ZNormSwitch.Items = {'0', '1'};
            app.ZNormSwitch.ItemsData = [0 1];
            app.ZNormSwitch.Value = 0;
            app.ResetAutoRotationButton = uibutton(app.GridLayout8, 'push');
            app.ResetAutoRotationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAutoRotationButtonPushed, true);
            app.ResetAutoRotationButton.Text = 'Reset';
            app.LayerdefinitionPanel = uipanel(app.GridLayout5);
            app.LayerdefinitionPanel.Title = 'Layer definition';
            app.GridLayout10 = uigridlayout(app.LayerdefinitionPanel);
            app.ShowLayerSpinnerLabel = uilabel(app.GridLayout10);
            app.ShowLayerSpinnerLabel.Text = 'Show Layer';
            app.ShowLayerSpinner = uispinner(app.GridLayout10);
            app.ShowLayerSpinner.Limits = [0 Inf];
            app.ResetLayerHeightButton = uibutton(app.GridLayout10, 'push');
            app.ResetLayerHeightButton.Text = 'Reset';
            app.LayerThicknessmSpinnerLabel = uilabel(app.GridLayout10);
            app.LayerThicknessmSpinnerLabel.Text = 'Layer Thickness (m)';
            app.LayerThicknessSpinner = uispinner(app.GridLayout10);
            app.LayerThicknessSpinner.Step = 0.01;
            app.LayerThicknessSpinner.Limits = [0 Inf];
            app.TotalHeightmSpinnerLabel = uilabel(app.GridLayout10);
            app.TotalHeightmSpinnerLabel.Text = 'Total Height (m)';
            app.TotalHeightSpinner = uispinner(app.GridLayout10);
            app.TotalHeightSpinner.Step = 0.01;
            app.TotalHeightSpinner.Limits = [0 Inf];
            app.TotalHeightSpinner.ValueChangedFcn = createCallbackFcn(app, @TotalHeightSpinnerValueChanged, true);
            app.TotalHeightSpinner.Value = 0.2;
            app.ApproxLayersLabel = uilabel(app.GridLayout10);
            app.ApproxLayersLabel.Text = 'Approx Layers';
            app.ApproxLayersSpinner = uispinner(app.GridLayout10);
            app.ApproxLayersSpinner.Limits = [1 Inf];
            app.ApproxLayersSpinner.Value = 1;
            app.DepthInfo = uitextarea(app.GridLayout5);
            app.ImageTab = uitab(app.TabGroup3);
            app.ImageTab.Title = 'Image';
            app.GridLayout6 = uigridlayout(app.ImageTab);
            app.GreendetectionPanel = uipanel(app.GridLayout6);
            app.GreendetectionPanel.Title = 'Green detection';
            app.LAITab = uitab(app.TabGroup3);
            app.LAITab.Tooltip = {'Compute Leave Area Index and otpion to finetune the used equasions.'};
            app.LAITab.Title = 'LAI';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Tooltip = {'Export the data'};
            app.ExportTab.Title = 'Export';
            app.ExportasDropDownLabel = uilabel(app.ExportTab);
            app.ExportasDropDownLabel.Text = 'Export as';
            app.ExportasDropDown = uidropdown(app.ExportTab);
            app.GetDataButton = uibutton(app.ExportTab, 'push');
            app.GetDataButton.Text = 'Get Data';
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.TextArea = uitextarea(app.LogTab);
            app.Button = uibutton(app.LogTab, 'push');
            app.Button2 = uibutton(app.LogTab, 'push');
            app.Button2.Text = 'Button2';
            % Show the figure after all components are created
            app.LAIDetectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testCase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LAIDetectionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LAIDetectionUIFigure)
        end
    end
end