classdef testCase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel  matlab.ui.control.Label
        TabGroup               matlab.ui.container.TabGroup
        ImportTab              matlab.ui.container.Tab
        GridLayout             matlab.ui.container.GridLayout
        GridLayout3            matlab.ui.container.GridLayout
        StepSpinnerLabel       matlab.ui.control.Label
        ResetCorrSpinButton    matlab.ui.control.Button
        OrigincorrectionLabel  matlab.ui.control.Label
        XCorrSpinner           matlab.ui.control.Spinner
        XSpinnerLabel          matlab.ui.control.Label
        YCorrSpinner           matlab.ui.control.Spinner
        StepCorrSpinner        matlab.ui.control.Spinner
        YSpinnerLabel          matlab.ui.control.Label
        GridLayout2            matlab.ui.container.GridLayout
        AreamEditField         matlab.ui.control.NumericEditField
        AreamEditFieldLabel    matlab.ui.control.Label
        ErrorLabel             matlab.ui.control.Label
        SearchFileButton       matlab.ui.control.Button
        bagFile                matlab.ui.control.EditField
        content2               matlab.ui.control.Label
        LoadDataButton         matlab.ui.control.Button
        RGBImg                 matlab.ui.control.UIAxes
        DepthImg               matlab.ui.control.UIAxes
        ExamineTab             matlab.ui.container.Tab
        ExportTab              matlab.ui.container.Tab
    end

    
    properties (Access = private)
        RawBagData % The data imported from the .bag file untouched
        OriginalRGBImg % The original RGB image extracted from the .bag file
        OriginalDepthImg % The original Depth image extracted from the .bag file
        FilePath % The path to the file
        AreaMSquare % The given area of the data
    end
    
    methods (Access = private)
        
        function computeData(app, bagData)

            curSelColor = select(bagData,'Topic','/device_0/sensor_1/Color_0/image/data');
            allImColor = readMessages(curSelColor, 'DataFormat', 'struct');
            app.OriginalRGBImg = rosReadImage(allImColor{1});

            curSelDepth = select(bagData,'Topic','/device_0/sensor_0/Depth_0/image/data');
            allImDepth = readMessages(curSelDepth, 'DataFormat', 'struct');
            imDepth = double(rosReadImage(allImDepth{1}));
            %imDepthNormalised = imDepth / max(max(imDepth));

            curSelDepth = select(bagData,'Topic','/device_0/sensor_0/option/Depth_Units/value');
            depthVal = readMessages(curSelDepth, 'DataFormat', 'struct');
            app.OriginalDepthImg = imDepth .* depthVal{1}.Data; 
            

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadBatFile(app, event)
            % reset the current values
            clc;
            
            %path = "ImgLoad/";
            file = app.bagFile.Value;
            
            errorFlag = 0;


            try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                end
                app.RawBagData = rosbag(file);
            catch
                if errorFlag == 0  % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.ErrorLabel.Text = "Something wrong with the given path.";
                end
            end

            try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                end
                app.AreaMSquare = app.AreamEditField.Value;
                disp(app.AreaMSquare)
            catch
                if errorFlag == 0  % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.ErrorLabel.Text = "Something wrong with the given path.";
                end
            end

            try
                if errorFlag == 0 % If no error has occured so far
                    app.ErrorLabel.Visible = "off";
                end
                computeData(app, app.RawBagData); % Compute the image as rgb and depth image and return this
            catch
                if errorFlag == 0 % If no error has occured so far
                    errorFlag = 1; % Set the error flag
                    app.ErrorLabel.Visible = "on"; % Show error when file not found
                    app.ErrorLabel.Text = "Can't compute data.";
                end
            end 
            % https://www.mathworks.com/matlabcentral/answers/418893-how-to-display-an-image-in-ui-figure-on-app-designer
            imshow(app.OriginalRGBImg, 'Parent',app.RGBImg);
            imshow(app.OriginalDepthImg, 'Parent',app.DepthImg);

            
        end

        % Button pushed function: SearchFileButton
        function SearchFileButtonPushed(app, event)
            % https://www.mathworks.com/help/matlab/ref/uigetfile.html

            [file,path] = uigetfile('*.bag');
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                app.FilePath = fullfile(path,file); %Transfer filepath
                app.bagFile.Value = app.FilePath; % set filepath in editable field
            end
        end

        % Button pushed function: ResetCorrSpinButton
        function ResetCorrSpinButtonPushed(app, event)
            % Reset the correction values to 0
            app.XCorrSpinner.Value = 0.0;
            app.YCorrSpinner.Value = 0.0;
            app.StepCorrSpinner.Value = 0.0;
        end

        % Value changed function: StepCorrSpinner
        function StepCorrSpinnerValueChanged(app, event)
            value = app.StepCorrSpinner.Value;
            % change the step of XCorrSpinner and YCorrSpinner
            if value > 1
                app.XCorrSpinner.Step = value;
                app.YCorrSpinner.Step = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportTab = uitab(app.TabGroup);
            app.ImportTab.Title = 'Import';
            app.GridLayout = uigridlayout(app.ImportTab);
            app.DepthImg = uiaxes(app.GridLayout);
            title(app.DepthImg, 'Depth')
            app.DepthImg.XTick = [];
            app.DepthImg.YTick = [];
            app.RGBImg = uiaxes(app.GridLayout);
            title(app.RGBImg, 'RGB')
            app.RGBImg.XTick = [];
            app.RGBImg.YTick = [];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.LoadDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBatFile, true);
            app.LoadDataButton.Text = 'Load Data';
            app.content2 = uilabel(app.GridLayout2);
            app.content2.Text = 'Path';
            app.bagFile = uieditfield(app.GridLayout2, 'text');
            app.bagFile.Placeholder = 'c:/img1.bat';
            app.SearchFileButton = uibutton(app.GridLayout2, 'push');
            app.SearchFileButton.ButtonPushedFcn = createCallbackFcn(app, @SearchFileButtonPushed, true);
            app.SearchFileButton.Text = 'Search File';
            app.ErrorLabel = uilabel(app.GridLayout2);
            app.ErrorLabel.Tag = 'FileImportError';
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Error';
            app.AreamEditFieldLabel = uilabel(app.GridLayout2);
            app.AreamEditFieldLabel.Text = 'Area m²';
            app.AreamEditField = uieditfield(app.GridLayout2, 'numeric');
            app.AreamEditField.ValueDisplayFormat = '%0.0f m²';
            app.AreamEditField.Placeholder = 'm²';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.YSpinnerLabel = uilabel(app.GridLayout3);
            app.YSpinnerLabel.Text = 'Y:';
            app.StepCorrSpinner = uispinner(app.GridLayout3);
            app.StepCorrSpinner.ValueChangedFcn = createCallbackFcn(app, @StepCorrSpinnerValueChanged, true);
            app.YCorrSpinner = uispinner(app.GridLayout3);
            app.XSpinnerLabel = uilabel(app.GridLayout3);
            app.XSpinnerLabel.Text = 'X:';
            app.XCorrSpinner = uispinner(app.GridLayout3);
            app.OrigincorrectionLabel = uilabel(app.GridLayout3);
            app.OrigincorrectionLabel.Text = 'Origin correction';
            app.ResetCorrSpinButton = uibutton(app.GridLayout3, 'push');
            app.ResetCorrSpinButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCorrSpinButtonPushed, true);
            app.ResetCorrSpinButton.Text = 'Reset';
            app.StepSpinnerLabel = uilabel(app.GridLayout3);
            app.StepSpinnerLabel.Text = 'Step';
            app.ExamineTab = uitab(app.TabGroup);
            app.ExamineTab.Title = 'Examine';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel = uilabel(app.UIFigure);
            app.madebyRobbeElsermansThomasKrampandAhmadShakleyaLabel.Text = 'made by: Robbe Elsermans, Thomas Kramp and Ahmad Shakleya';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testCase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end